classdef ISETBioCSFGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainView                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        retinalpatchPanel               matlab.ui.container.Panel
        radialeccentricitydegsSliderLabel  matlab.ui.control.Label
        radialeccentricitydegsSlider    matlab.ui.control.Slider
        polareccentricitydegsSlider     matlab.ui.control.Knob
        fieldofviewdegsSliderLabel      matlab.ui.control.Label
        fieldofviewdegsSlider           matlab.ui.control.Slider
        eyeSwitch                       matlab.ui.control.Switch
        retinalmagnificationfactorumdegEditFieldLabel  matlab.ui.control.Label
        retinalmagnificationfactorumdegEditField  matlab.ui.control.NumericEditField
        visualFieldView                 matlab.ui.control.UIAxes
        tasksPanel                      matlab.ui.container.Panel
        computeCSFButton                matlab.ui.control.Button
        exportscriptButton              matlab.ui.control.Button
        exportdataButton                matlab.ui.control.Button
        exportfigsButton                matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        psychometricfunctionestimationPanel  matlab.ui.container.Panel
        slope_minSpinnerLabel           matlab.ui.control.Label
        slope_minSpinner                matlab.ui.control.Spinner
        slope_maxSpinnerLabel           matlab.ui.control.Label
        slope_maxSpinner                matlab.ui.control.Spinner
        classifiertrainingtrialsSpinnerLabel  matlab.ui.control.Label
        classifiertrainingtrialsSpinner  matlab.ui.control.Spinner
        performancetrialsSpinnerLabel   matlab.ui.control.Label
        performancetrialsSpinner        matlab.ui.control.Spinner
        slope_deltaSpinnerLabel         matlab.ui.control.Label
        slope_deltaSpinner              matlab.ui.control.Spinner
        log10C_minSpinnerLabel          matlab.ui.control.Label
        log10C_minSpinner               matlab.ui.control.Spinner
        log10C_maxSpinnerLabel          matlab.ui.control.Label
        log10C_maxSpinner               matlab.ui.control.Spinner
        log10C_deltaSpinnerLabel        matlab.ui.control.Label
        log10C_deltaSpinner             matlab.ui.control.Spinner
        psychometricFunctionView        matlab.ui.control.UIAxes
        conemosaicPanel                 matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        eccvaryingmacularpigmentCheckBox  matlab.ui.control.CheckBox
        eccvaryingaperturelightgatheringCheckBox  matlab.ui.control.CheckBox
        eccvaryingapertureblurCheckBox  matlab.ui.control.CheckBox
        domainSwitch                    matlab.ui.control.Switch
        integrationtimemsecLabel        matlab.ui.control.Label
        integrationtimemsecSpinner      matlab.ui.control.Spinner
        Switch_3Label                   matlab.ui.control.Label
        psfGridOverlaySwitch            matlab.ui.control.Switch
        Label_2                         matlab.ui.control.Label
        stimulusOverlaySwitch           matlab.ui.control.Switch
        eccvaryingmacularpigmentdynamicCheckBox  matlab.ui.control.CheckBox
        Label_3                         matlab.ui.control.Label
        activationSwitch                matlab.ui.control.Switch
        mosaicView                      matlab.ui.control.UIAxes
        opticsPanel                     matlab.ui.container.Panel
        conesSwitch                     matlab.ui.control.Switch
        PolanssubjectLabel              matlab.ui.control.Label
        PolanssubjectSpinner            matlab.ui.control.Spinner
        eccvaryingopticalblurgridCheckBox  matlab.ui.control.CheckBox
        xnodesSpinnerLabel              matlab.ui.control.Label
        xnodesSpinner                   matlab.ui.control.Spinner
        ynodesSpinnerLabel              matlab.ui.control.Label
        ynodesSpinner                   matlab.ui.control.Spinner
        pupildiametermmLabel            matlab.ui.control.Label
        pupildiametermmSpinner          matlab.ui.control.Spinner
        psfView                         matlab.ui.control.UIAxes
        stimulusPanel                   matlab.ui.container.Panel
        cLSpinnerLabel                  matlab.ui.control.Label
        cMSpinner                       matlab.ui.control.Spinner
        cSSpinner                       matlab.ui.control.Spinner
        cLSpinner                       matlab.ui.control.Spinner
        McontLabel                      matlab.ui.control.Label
        ScontLabel                      matlab.ui.control.Label
        w_minSpinner                    matlab.ui.control.Spinner
        w_maxSpinner                    matlab.ui.control.Spinner
        w_stepSpinner                   matlab.ui.control.Spinner
        w_minSpinnerLabel               matlab.ui.control.Label
        w_maxSpinnerLabel               matlab.ui.control.Label
        w_stepSpinnerLabel              matlab.ui.control.Label
        flashmsecSpinnerLabel           matlab.ui.control.Label
        flashmsecSpinner                matlab.ui.control.Spinner
        sizedegsSpinnerLabel            matlab.ui.control.Label
        sizedegsSpinner                 matlab.ui.control.Spinner
        generateButton                  matlab.ui.control.Button
        sfcdegLabel                     matlab.ui.control.Label
        spatialFrequencySpinner         matlab.ui.control.Spinner
        stimulusGenerationStatusReport  matlab.ui.control.EditField
        yPosdegsSpinnerLabel            matlab.ui.control.Label
        yPosdegsSpinner                 matlab.ui.control.Spinner
        xPosdegsSpinner_2Label          matlab.ui.control.Label
        xPosdegsSpinner_2               matlab.ui.control.Spinner
        envelopeLabel                   matlab.ui.control.Label
        envelopeDropDown                matlab.ui.control.DropDown
        Label_4                         matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        stimulusView                    matlab.ui.control.UIAxes
        contrastsensitivityfunctionPanel  matlab.ui.container.Panel
        sfSamplesSpinnerLabel           matlab.ui.control.Label
        sfSamplesSpinner                matlab.ui.control.Spinner
        sfMaxLabel                      matlab.ui.control.Label
        sfMaxSpinner                    matlab.ui.control.Spinner
        sfMinLabel                      matlab.ui.control.Label
        sfMinSpinner                    matlab.ui.control.Spinner
        csfView                         matlab.ui.control.UIAxes
        fixationaleyemovementsPanel     matlab.ui.container.Panel
        componentsLabel                 matlab.ui.control.Label
        componentsDropDown              matlab.ui.control.DropDown
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        
        % Constants
        maxEcc = 25.0;                                  % Maximum eccentricity
        
        % User settable simulation parameters (mosaic)
        mosaicRadialEccentricityDegs = 0;               % Mosaic radial eccentricity in degrees
        mosaicPolarEccentricityDegs = 0;                % Mosaic polar eccentricity in degrees
        mosaicSizeDegs = 0.1;                           % Mosaic size in degrees
        mosaicDomain = 'deg';                           % Mosaic domain ('deg' or 'um');
        
        % User settable simulation parameters (optics)
        opticsPolansSubjectID;                          % Subject ID (Polans database)
        opticsPupilDiameterMM;                          % Pupil diameter in mm
        opticsVisualizedWavelength = 550;               % Wavelength at which we visualize the PSF
        
 
        % User settable stimulus parameters
        wavelengthMinValue;                             % The minimum wavelength
        wavelengthMaxValue;                             % The maximum wavelength
        wavelengthStepSize;                             % The wavelength step size
        
        stimLcontrast;                                  % The stimulus S-cone contrast
        stimMcontrast;                                  % The stimulus M-cone contrast
        stimScontrast;                                  % The stimulus S-cone contrast
        
        stimDurationMsec;                               % The stimulus duration in msec
        stimSizeDegs;                                   % The stimulus size in degrees
        stimSpatialFrequencyCyclesPerDeg;               % The stimulus spatial frequency in c/deg
        stimEnvelope;                                   % The stimulus envelope
        
        
        % User settable psychometric estimation parameter
        psychometricFunctionMinSlope;                   % The min slope of the psychometric function
        psychometricFunctionMaxSlope;                   % The min slope of the psychometric function
        psychometricFunctionMinLogThreshold;            % The min log10(threshold) of the psychometric function
        psychometricFunctionMaxLogThreshold;            % The max log10(threshold) of the psychometric function
        
        % User settable CSF spatial frequency support
        csfMinSpatialFrequency;                         % The min spatial frequency examined
        csfMaxSpatialFrequency;                         % The max spatial frequency examined
        csfSamplesSpatialFrequency;                     % The number of spatial frequency samples examined
            
        % Switches
        overlayMosaicOnStimulus;                        % Whether to overlay the mosaic ontop of the stimulus
        mosaicDisplayMode;                              % Whether to display cone types or cone activations
        
        % Computed properties
        mosaicEccentricityDegs;                         % Eccentricity of the cone mosaic, computed from radial&polar eccentricity
        demoStimulusScene;                              % Demo stimulus scene
        demoMosaicActivation;                           % Mosaic activation to the demo stimulus scene
        
        components = struct();                          % Struct containing simulation components, eg. coneMosaic
        
        
        % Flags indicating whether we need to recompute certain components
        % before conducting any response computations
        recomputeOptics = false;
        
        % Plot handles
        mosaicOnVisualFieldPlotHandle;                  % The mosaic on visual field plot handle
    end
    
     
    methods (Access = private)
        
        % Method called from the startup method as soon as the GUI is
        % presented to initialize all simulation params
        function initializeSimulationParams(app) 
            % Initialize visual field params
            app.decodeRadialEccentricityDegsSlider(app.radialeccentricitydegsSlider.Value); 
            app.decodePolarEccentricityDegsSlider(app.polareccentricitydegsSlider.Value);          
            app.decodeSizeDegsSlider(app.fieldofviewdegsSlider.Value);
            
            % Initialize stimulus params
            app.wavelengthMinValue = app.w_minSpinner.Value;                             
            app.wavelengthMaxValue = app.w_maxSpinner.Value;                        
            app.wavelengthStepSize = app.w_stepSpinner.Value; 
            
            app.stimLcontrast = app.cLSpinner.Value;
            app.stimMcontrast = app.cMSpinner.Value;
            app.stimScontrast = app.cSSpinner.Value;
            
            app.stimDurationMsec = app.flashmsecSpinner.Value;
            app.stimSizeDegs  = app.sizedegsSpinner.Value;
            app.stimSpatialFrequencyCyclesPerDeg = app.spatialFrequencySpinner.Value;
            
            app.stimEnvelope = app.envelopeDropDown.Value;
            
            
            % Initialize psychometric function params
            app.psychometricFunctionMinSlope = app.slope_minSpinner.Value;
            app.psychometricFunctionMaxSlope = app.slope_maxSpinner.Value;
            app.psychometricFunctionMinLogThreshold = app.log10C_minSpinner.Value;
            app.psychometricFunctionMaxLogThreshold = app.log10C_maxSpinner.Value;
        
            % Initialize CSF spatial frequency support params
            app.csfMinSpatialFrequency = app.sfMinSpinner.Value;
            app.csfMaxSpatialFrequency = app.sfMaxSpinner.Value;
            app.csfSamplesSpatialFrequency = app.sfSamplesSpinner.Value;
        
        
            % Initialize optics params
            app.opticsPolansSubjectID = app.PolanssubjectSpinner.Value;
            app.opticsPupilDiameterMM = app.pupildiametermmSpinner.Value;
            
            % Initialize switches
            app.overlayMosaicOnStimulus = app.stimulusOverlaySwitch.Value;
            app.mosaicDisplayMode = app.activationSwitch.Value;
        end
        
        % Method called from the startup method as soon as the GUI is
        % presented. Used to set slider values etc.
        function initializeGUIcomponents(app)
            app.updateRadialEccentricitySliderText();
            app.updateMosaicSizeSliderText();
            app.initializePolarEccentricitySliderTicks();
            drawnow;
        end
        
        % Method called from the startup method as soon as the GUI is
        % presented. Used to initialize all views.
        function initializeViews(app)
            app.initializeVisualFieldView();
            app.initializeMosaicView();
            app.initializePSFView();
            app.initializeStimulusView();
            app.initializePsychometricFunctionView();
            app.initializeCSFView();
            app.updateVisualFieldView();
            drawnow;
        end
        
        function initializeMosaicView(app)
            plot(app.mosaicView, 0.01*cosd(0:10:360), 0.01*sind(0:10:360), 'k-');
            box(app.mosaicView, 'on');
            set(app.mosaicView, 'XLim', [-0.1 0.1], 'YLim', [-0.1 0.1], ...
                'XTick', [-0.1 0 0.1], 'YTick', [-0.1 0 0.1]);
            xlabel(app.mosaicView, 'degrees');
            drawnow;
            pause(0.1);
        end
        
        function initializePSFView(app)
            plot(app.psfView, 0.5*cosd(0:10:360), 0.5*sind(0:10:360), 'k-');
            spatialSupportArcMinRange = 10;
            set(app.psfView, 'XLim', spatialSupportArcMinRange*[-0.5 0.5], 'YLim', spatialSupportArcMinRange*[-0.5 0.5], ...
                'XTick', -10:1:10, 'YTick', -10:1:10, 'FontSize', 14);
            box(app.psfView, 'on');
            grid(app.psfView, 'on');
            xlabel(app.psfView, 'arc min.');
            ylabel(app.psfView, 'arc min.');
            drawnow;
            pause(0.1);
        end
        
        function initializeStimulusView(app)
            spatialSupportDegs = 1;
            set(app.stimulusView, 'XLim', spatialSupportDegs*[-0.5 0.5], 'YLim', spatialSupportDegs*[-0.5 0.5], ...
                'XTick', -10:0.5:10, 'YTick', -10:0.5:10, 'YTickLabel', {}, 'XTickLabel', {}, 'XColor', 'none', 'YColor', 'none');
            plot(app.stimulusView, spatialSupportDegs*0.5*[-1 1 1 -1 -1], spatialSupportDegs*0.5*[-1 -1 1 1 -1], 'k-');
            axis(app.stimulusView, 'square');
            box(app.stimulusView, 'on');
        end
        
        
        function initializePsychometricFunctionView(app)
            set(app.psychometricFunctionView, 'XLim', [-5 0], 'YLim', [0.49 1.01], 'XScale', 'linear');
            set(app.psychometricFunctionView, 'XTick', [-5 -4 -3 -2 -1 0]);
            xlabel(app.psychometricFunctionView, 'log10(contrast)');
            ylabel(app.psychometricFunctionView, 'pCorrect');
            grid(app.psychometricFunctionView, 'on');
            box(app.psychometricFunctionView, 'on');
        end
        
        function initializeCSFView(app)
            set(app.csfView, 'XLim', [0.5 60], 'YLim', [1 1000], ...
                'XTick', [1 3 10 30], 'YTick', [1 3 10 30 100 300 1000], ...
                'XScale', 'log', 'YScale', 'log', 'FontSize', 14);
            box(app.csfView, 'on');
            grid(app.csfView, 'on');
            xlabel(app.csfView, 'spatial frequency (c/deg)');
            ylabel(app.csfView, 'contrast sensitivity');
        end
        
        function initializeVisualFieldView(app)
            plot(app.visualFieldView, [-32 32], [0 0], 'k-', 'LineWidth', 1.5, 'Color', [0.5 0.5 0.5]);
            hold(app.visualFieldView, 'on');
            plot(app.visualFieldView, [0 0], [-32 32], 'k-', 'LineWidth', 1.5, 'Color', [0.5 0.5 0.5]);
            for radius = [0.15 0.3 0.6 1.2 2.5 5 10 20]
                logRadius = app.maxEcc*(1+log10(radius))/(1+log10(app.maxEcc));
                plot(app.visualFieldView, logRadius*cosd(0:5:360), logRadius*sind(0:5:360), 'k-', 'Color', [0.5 0.5 0.5], 'LineWidth', 1.0);
            end
            app.mosaicOnVisualFieldPlotHandle = scatter(app.visualFieldView, 0, 0, 14*14, 'ro', 'MarkerFaceColor', [1 0.5 0.5], 'MarkerFaceAlpha', 0.5);
            hold(app.visualFieldView, 'off');
            set(app.visualFieldView, 'XTick', 0, 'YTick', 0, 'XLim', app.maxEcc*[-1 1], 'YLim', app.maxEcc*[-1 1], 'FontSize', 14);
            set(app.visualFieldView, 'Color', 'none', 'XColor', 'none', 'YColor', 'none');
            set(app.visualFieldView, 'XTickLabel', '0');
            set(app.visualFieldView, 'YTickLabel', '0');
            box(app.visualFieldView, 'off');
            xlabel(app.visualFieldView, 'x (degs)');
            ylabel(app.visualFieldView, 'y (degs)');
        end
        
        function initializeSimulationComponents(app)
            % Start the parpool
            dialogBox = uiprogressdlg(app.mainView,'Title','Please Wait',...
                'Message',sprintf('This app requires that both (i) isetbio and (ii) ISETBioCSFGenerator,\n available at: \n\t-https://github.com/isetbio/isetbio\n\t-https://github.com/isetbio/ISETBioCSFGenerator.git \nare on the user''s path.\n\nWaiting for parallel pool engine to wake up...'));
            dialogBox.Value = 0.2; 
            pause(.01)
            poolobj = gcp('nocreate'); 
            if isempty(poolobj)
                parpool('local');
            end
            pause(1.0)
            
            % Regenerate the cone mosaic
            app.regenerateMosaicAndOptics(dialogBox, 0.5, 0.8);
            
            % Regenerate the demo stimulus scene
            app.regenerateDemoScene(dialogBox, 0.9, 1.0);
            app.updateStimulusView();
            app.updatePsychometricFunctionView();
            app.updateCSFView();
            
            % Close dialog box
            close(dialogBox);
        end
        
        function regenerateDemoScene(app, dialogBox, currentProgressValue, nextProgressValue)
            % Create progress bar
            if (currentProgressValue == 0) 
                dialogBox = uiprogressdlg(app.mainView,'Title','Please Wait',...
                'Message','');
                pause(.01)
            end
            
            % Update progress bar
            deltaProgressValue = (nextProgressValue-currentProgressValue)/2;
            dialogBox.Value = currentProgressValue+deltaProgressValue; 
            dialogBox.Message = 'Generating stimulus scene';
            pause(.01)
            
            % Generate demo stimulus
            app.generateDemoStimulus();
            
            % Visualize demo stimulus
            app.updateStimulusView();
            
            % Close progress bar
            if (currentProgressValue == 0)
                close(dialogBox);
            end
        end
        
        % Method to regenerate the cone mosaic and update the current progress bar
        function regenerateMosaicAndOptics(app, dialogBox, currentProgressValue, nextProgressValue)
            
            % Create progress bar
            if (currentProgressValue == 0) 
                dialogBox = uiprogressdlg(app.mainView,'Title','Please Wait',...
                'Message','');
                pause(.01)
            end
            
            % Update progress bar
            deltaProgressValue = (nextProgressValue-currentProgressValue)/3;
            dialogBox.Value = currentProgressValue+deltaProgressValue; 
            dialogBox.Message = 'Generating cone mosaic';
            pause(.01)
            
            % Generate new cone mosaic
            app.regenerateMosaic();
            
            % Update progress bar
            dialogBox.Value = currentProgressValue+1.3*deltaProgressValue; 
            dialogBox.Message = 'Generating optics';
            pause(.01)
            
            % Generate optics appropriate for the mosaic's eccentricity
            app.regenerateOptics();
            
            if (strcmp(app.mosaicDisplayMode, 'activation'))
                % Update progress bar
                dialogBox.Value = currentProgressValue+1.6*deltaProgressValue; 
                dialogBox.Message = 'Computing mosaic response';
                pause(.01);

                % Compute the optical image to the demo stimulus scene
                app.components.optics = oiCompute(app.demoStimulusScene, app.components.optics);
                
                % Compute mean response
                app.demoMosaicActivation = app.components.coneMosaic.compute(app.components.optics);
            end
            
            % Update progress bar
            dialogBox.Value = currentProgressValue+2*deltaProgressValue; 
            dialogBox.Message = 'Rendering cone mosaic';
            pause(.01);
            
            % Visualize the  cone mosaic
            app.updateMosaicView();
            
            % Visualized the psf
            app.updatePSFView();
            
            % Close progress bar
            if (currentProgressValue == 0)
                close(dialogBox);
            end
        end
        
        function regenerateMosaic(app)
            % Generate cone mosaic
            app.components.coneMosaic = cMosaic(...
                'sizeDegs', app.mosaicSizeDegs*[1 1], ... 
                'eccentricityDegs', app.mosaicEccentricityDegs, ...
                'wave', app.wavelengthMinValue : app.wavelengthStepSize: app.wavelengthMaxValue ...
            );
        end
        
        
        function regenerateOptics(app)
            % Generate optics appropriate for the mosaic's eccentricity
            [oiEnsemble, psfEnsemble] = ...
                app.components.coneMosaic.oiEnsembleGenerate(app.mosaicEccentricityDegs, ...
                'zernikeDataBase', 'Polans2015', ...
                'subjectID', app.opticsPolansSubjectID, ...
                'pupilDiameterMM', app.opticsPupilDiameterMM);
           
            % Update all components with new optics
            app.components.psf = psfEnsemble{1};
            app.components.optics = oiEnsemble{1};
        end
        
        function refreshStimulus(app)
            app.regenerateDemoScene([], 0, 1);
            if (~strcmp(app.overlayMosaicOnStimulus, '0'))
                app.updateMosaicView();
            elseif strcmp(app.mosaicDisplayMode, 'activation')
                app.recomputeMosaicResponseToDemoStimulusScene();
                app.updateMosaicView();
            end
        end
        
        function generateDemoStimulus(app)
            defaultGratingParams = sceGrating();
            customGratingParams = defaultGratingParams;
            
            % For the demo stimulus, do not throw an error if out-of-gamut
            customGratingParams.warningInsteadOfErrorOnOutOfGamut = true;
            
            % Make it 64x64 pixels (fast)
            customGratingParams.pixelsNum = 256;
            
            % Spectral support
            customGratingParams.spectralSupport = (app.wavelengthMinValue : app.wavelengthStepSize: app.wavelengthMaxValue);
            
            % Configure an L-M grating
            customGratingParams.coneContrastModulation = [app.stimLcontrast app.stimMcontrast app.stimScontrast]/100;
    
            % 90 deg orientation, and a cosine spatial phase
            customGratingParams.spatialPhaseDegs = 0;
            customGratingParams.orientationDegs = 90;
            customGratingParams.spatialModulation =  'harmonic';
            
            % Spatial frequency
            customGratingParams.spatialFrequencyCyclesPerDeg = app.stimSpatialFrequencyCyclesPerDeg;
    
            % Size and spatial envelope
            customGratingParams.fovDegs = app.stimSizeDegs;
            customGratingParams.spatialEnvelopeRadiusDegs = app.stimSizeDegs/2;
            switch (app.stimEnvelope)
                case 'disk'
                    customGratingParams.spatialEnvelope = 'disk';
                case 'rect'
                    customGratingParams.spatialEnvelope = 'square';
                case 'soft'
                    customGratingParams.spatialEnvelope = 'Gaussian';
                    customGratingParams.spatialEnvelopeRadiusDegs = app.stimSizeDegs/4;
            end
            
            
            customGratingParams.minPixelsNumPerCycle = 30;
            
            % Duration
            customGratingParams.frameDurationSeconds = app.stimDurationMsec/1000;
    
            % Configure temporal modulation
            customGratingParams.temporalModulation = 'flashed';
            customGratingParams.temporalModulationParams =  struct(...                      
                'stimOnFrameIndices', 1, ...          
                'stimDurationFramesNum', 1);
            
            
            % Instantiate a sceneEngine with the above sceneComputeFunctionHandle 
            % and the custom grating params.
            sceneComputeFunction = @sceGrating;
            theSceneEngine = sceneEngine(sceneComputeFunction, customGratingParams);
    
            % Compute the scene sequence
            testContrast = 1.0;
            [theSceneSequence, ~, statusReport] = theSceneEngine.compute(testContrast);
          
            flagNames = fieldnames(statusReport);
            if (~isempty(flagNames))
                app.stimulusGenerationStatusReport.Value = '';
                for k = 1:numel(flagNames)
                    app.stimulusGenerationStatusReport.Value = sprintf('%s-%s',app.stimulusGenerationStatusReport.Value,flagNames{k});
                end
                app.stimulusGenerationStatusReport.Value = sprintf('%s-',app.stimulusGenerationStatusReport.Value);
                app.stimulusGenerationStatusReport.FontColor = [0 0 1];
                app.stimulusGenerationStatusReport.FontWeight = 'Bold';
                app.stimulusGenerationStatusReport.BackgroundColor = [1 1 0];
            else
                app.stimulusGenerationStatusReport.Value = 'OK';
                app.stimulusGenerationStatusReport.FontColor = [0 0 0];
                app.stimulusGenerationStatusReport.BackgroundColor = [1 1 1];
            end
            
            % Set the stimulus demo scene
            app.demoStimulusScene = theSceneSequence{1};
        end
        
        function recomputeMosaicResponseToDemoStimulusScene(app)
            % Create progress bar
            dialogBox = uiprogressdlg(app.mainView,'Title','Please Wait','Message','');
            
            % Update progress bar
            dialogBox.Value = 0.3;
            dialogBox.Message = 'Computing optical image ...';
            pause(.01)
            
            % Compute the optical image to the demo stimulus scene
            app.components.optics = oiCompute(app.demoStimulusScene, app.components.optics);
            
            % Update progress bar
            dialogBox.Value = 0.6;
            dialogBox.Message = 'Computing mean cone excitations ...';
            pause(.01)
            
            % Compute mean response
            app.demoMosaicActivation = app.components.coneMosaic.compute(app.components.optics);
            
            % Update progress bar
            close(dialogBox);
        end
        
        function decodeRadialEccentricityDegsSlider(app, value)
            actualValues = round(logspace(log10(0.15),log10(40), 9)*100)/100;
            actualValues = [0 actualValues(1:end-1)];
            app.mosaicRadialEccentricityDegs = interp1(0:(numel(actualValues)-1), actualValues, value);
            app.mosaicEccentricityDegs(1) = app.mosaicRadialEccentricityDegs * cosd(app.mosaicPolarEccentricityDegs);
            app.mosaicEccentricityDegs(2) = app.mosaicRadialEccentricityDegs * sind(app.mosaicPolarEccentricityDegs);
        end
        
        function decodePolarEccentricityDegsSlider(app, value)
            app.mosaicPolarEccentricityDegs =-value;
            app.mosaicEccentricityDegs(1) = app.mosaicRadialEccentricityDegs * cosd(app.mosaicPolarEccentricityDegs);
            app.mosaicEccentricityDegs(2) = app.mosaicRadialEccentricityDegs * sind(app.mosaicPolarEccentricityDegs);
        end
        
        function decodeSizeDegsSlider(app, value)
            actualValues = [0.07 0.15 0.3 0.6 1.2 2.5 5 10];
            app.mosaicSizeDegs  = interp1(1:numel(actualValues), actualValues, value);
        end
        
        function updateRadialEccentricitySliderText(app)
            radialEccentricity = sqrt(sum(app.mosaicEccentricityDegs.^2,2));
            if (app.mosaicEccentricityDegs < 1.0)
                 app.radialeccentricitydegsSliderLabel.Text = sprintf('radial eccentricity (degs): %2.2f', radialEccentricity );
            elseif (app.mosaicEccentricityDegs < 10.0)
                 app.radialeccentricitydegsSliderLabel.Text = sprintf('radial eccentricity (degs): %2.1f', radialEccentricity);
            else
                 app.radialeccentricitydegsSliderLabel.Text = sprintf('radial eccentricity (degs): %2.0f', radialEccentricity);
            end
        end
        
        function updateMosaicSizeSliderText(app)
            if (app.mosaicSizeDegs < 1.0)
                 app.fieldofviewdegsSliderLabel.Text =  sprintf('field of view (degs): %2.2f x %2.2f ', app.mosaicSizeDegs, app.mosaicSizeDegs);
            elseif (app.mosaicSizeDegs < 10.0)
                 app.fieldofviewdegsSliderLabel.Text = sprintf('field of view (degs): %2.1f x %2.1f', app.mosaicSizeDegs, app.mosaicSizeDegs);
            else
                 app.fieldofviewdegsSliderLabel.Text = sprintf('field of view(degs): %2.0f x %2.0f', app.mosaicSizeDegs, app.mosaicSizeDegs);
            end
        end
        
        function initializePolarEccentricitySliderTicks(app)
            app.polareccentricitydegsSlider.MajorTicks = 0:45:360;
            app.polareccentricitydegsSlider.MajorTickLabels = {'360', '315', '270', '225', '180',  '135',  '90',   '45', '0'};
            app.polareccentricitydegsSlider.MinorTicks = [];
        end
        
        function updateVisualFieldView(app)
            app.mosaicEccentricityDegs = app.mosaicRadialEccentricityDegs * [cosd(app.mosaicPolarEccentricityDegs) sind(app.mosaicPolarEccentricityDegs)];
            if (log10(app.mosaicRadialEccentricityDegs)>-1)
                logRadius = app.maxEcc*(1+log10(app.mosaicRadialEccentricityDegs))/(1+log10(app.maxEcc));
            else
                logRadius = 0;
            end
            
            set(app.mosaicOnVisualFieldPlotHandle, ...
                'XData',logRadius*cosd(app.mosaicPolarEccentricityDegs), ...
                'YData',logRadius*sind(app.mosaicPolarEccentricityDegs));
            app.updateRadialEccentricitySliderText();
            drawnow
        end

        % Method to update the mosaicView
        function updateMosaicView(app)
            
            switch (app.mosaicDomain)
                case 'deg'
                    domain = 'degrees';
                case 'um'
                    domain = 'microns';
            end
           
            
            if (strcmp(app.mosaicDisplayMode, 'activation'))
                app.components.coneMosaic.visualize(...
                    'figureHandle', app.mainView, ...
                    'axesHandle', app.mosaicView, ...
                    'activation', app.demoMosaicActivation, ...
                    'crossHairsOnFovea', true, ...
                    'domain', domain, ...
                    'noYLabel', true, ...
                    'fontSize', 14, ...
                    'plotTitle',  ' ');
            else
                showDensityContours = ~true;
                if (showDensityContours) 
                    app.components.coneMosaic.visualize(...
                    'figureHandle', app.mainView, ...
                    'axesHandle', app.mosaicView, ...
                    'labelCones', true, ...
                    'densityContourOverlay', true, ...
                    'densityContourLevelLabelsDisplay', true, ...
                    'crossHairsOnFovea', true, ...
                    'domain', domain, ...
                    'noYLabel', true, ...
                    'fontSize', 14, ...
                    'plotTitle',  ' ');
                else
                    app.components.coneMosaic.visualize(...
                    'figureHandle', app.mainView, ...
                    'axesHandle', app.mosaicView, ...
                    'labelCones', true, ... %strcmp(app.overlayMosaicOnStimulus, '0'), ...
                    'crossHairsOnFovea', true, ...
                    'domain', domain, ...
                    'noYLabel', true, ...
                    'fontSize', 14, ...
                    'plotTitle',  ' ');
                end
                
            
                if (~strcmp(app.overlayMosaicOnStimulus, '0'))
                    hold(app.mosaicView, 'on');
                    [imPlot, ~, ~, mask] = app.renderStimulusOnAxes(app.mosaicView);
                    imPlot.AlphaData = 0.7*mask;
                    hold(app.mosaicView, 'off');
                end
            end
 
            % Set the current retinal magnification factor
            app.retinalmagnificationfactorumdegEditField.Value = app.components.coneMosaic.micronsPerDegree;
        end
        
        % Method to update the displayed PSF
        function updatePSFView(app)
            % Find index of visualized wavelength
            [~, wIdx] = min(abs(app.components.psf.supportWavelength-app.opticsVisualizedWavelength));
            
            % Extract PSF at the desired wavelength
            wavePSF = squeeze(app.components.psf.data(:,:,wIdx));
            
            % Normalize to unit amplitude
            wavePSF = wavePSF/max(wavePSF(:));
            zLevels = 0.1:0.1:0.9; xyRangeArcMin = 5;
            
            % Render it
            cla(app.psfView);
            PolansOptics.renderPSF(app.psfView, app.components.psf.supportX, app.components.psf.supportY, wavePSF, ...
                    xyRangeArcMin, zLevels,  gray(1024), [0 0 0], 'plotTitle',  ' ');
    
        end

        function [imPlot, xSupport, ySupport, mask] = renderStimulusOnAxes(app, axesHandle)
            rgbImage = sceneGet(app.demoStimulusScene, 'rgb');
            lumImage = sceneGet(app.demoStimulusScene, 'luminance');
            meanLum = sceneGet(app.demoStimulusScene, 'mean luminance');
            contrastImage = abs((lumImage-meanLum)/meanLum);
            contrastImage(contrastImage>1) = 1;
            contrastImage = contrastImage / max(contrastImage(:));
            mask = contrastImage;
            fovDegrees = sceneGet(app.demoStimulusScene, 'wAngular');
            xSupport = 1:size(rgbImage,2);
            xSupport = xSupport - mean(xSupport);
            xSupport = xSupport/max(abs(xSupport)) * fovDegrees * 0.5;
            ySupport = 1:size(rgbImage,1);
            ySupport = ySupport - mean(ySupport);
            ySupport = ySupport/max(abs(ySupport)) * fovDegrees * 0.5;
            
            % Render demo stimulus on the passed axes
            imPlot = image(axesHandle, xSupport, ySupport, rgbImage);
        end
        
        % Method to update the demo stimulus
        function updateStimulusView(app)
            [~, xSupport, ySupport] = app.renderStimulusOnAxes(app.stimulusView);
            set(app.stimulusView, 'XLim', [xSupport(1) xSupport(end)], 'YLim', [ySupport(1) ySupport(end)], ...
                'XTick', -10:0.5:10, 'YTick', -10:0.5:10, 'YTickLabel', {}, 'XTickLabel', {}, 'FontSize', 14);
        end
        
        % Method to update the psychometric function view
        function updatePsychometricFunctionView(app)
            % Compute ranges of psychometric functions
            lapse = 0; guess = 0.5;
            log10Cthreshold = -5:0.01:0;
            pCorrectMaxSlopeMaxThreshold = 1 - (lapse - (guess + lapse - 1)*exp(-10.^(app.psychometricFunctionMaxSlope*(log10Cthreshold - app.psychometricFunctionMaxLogThreshold)/20)));
            pCorrectMinSlopeMaxThreshold = 1 - (lapse - (guess + lapse - 1)*exp(-10.^(app.psychometricFunctionMinSlope*(log10Cthreshold - app.psychometricFunctionMaxLogThreshold)/20)));
            pCorrectMaxSlopeMinThreshold = 1 - (lapse - (guess + lapse - 1)*exp(-10.^(app.psychometricFunctionMaxSlope*(log10Cthreshold - app.psychometricFunctionMinLogThreshold)/20)));
            pCorrectMinSlopeMinThreshold = 1 - (lapse - (guess + lapse - 1)*exp(-10.^(app.psychometricFunctionMinSlope*(log10Cthreshold - app.psychometricFunctionMinLogThreshold)/20)));
            log10CthresholdCoords = [log10Cthreshold, fliplr(log10Cthreshold)];
            inBetweenPCorrectMaxSlopeCoords = [pCorrectMaxSlopeMinThreshold, fliplr(pCorrectMaxSlopeMaxThreshold)];
            inBetweenPCorrectMinSlopeCoords = [pCorrectMinSlopeMinThreshold, fliplr(pCorrectMinSlopeMaxThreshold)];
            
            % Plot ranges of psychometric functions as filled plots
            cla(app.psychometricFunctionView); hold(app.psychometricFunctionView, 'on');
            fill(app.psychometricFunctionView, log10CthresholdCoords, inBetweenPCorrectMaxSlopeCoords, [1 0.5 0.5], 'FaceAlpha', 0.5, 'EdgeAlpha', 1.0, 'EdgeColor', [1 0 0], 'LineWidth', 1.0);
            fill(app.psychometricFunctionView, log10CthresholdCoords, inBetweenPCorrectMinSlopeCoords, [0.5 0.5 1], 'FaceAlpha', 0.5, 'EdgeAlpha', 1.0, 'EdgeColor', [0 0 1], 'LineWidth', 1.0);
        end
            
        function sfSupport = decodeCSFsupport(app)
            sfSupport = logspace(log10(app.csfMinSpatialFrequency), log10(app.csfMaxSpatialFrequency), app.csfSamplesSpatialFrequency);
        end
        

        % Method to update the CSF view
        function updateCSFView(app)
            cla(app.csfView); hold(app.csfView, 'on');
            sfSupport = app.decodeCSFsupport(); 
            scatter(app.csfView, sfSupport, sfSupport * 0 + 100, 14*14, 'o', ...
                'LineWidth', 1.5, 'MarkerFaceColor', [1 0.5 0.5], 'MarkerFaceAlpha', 0.5, 'MarkerEdgeColor', [1 0 0], 'MarkerEdgeAlpha', 0.5);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initializeSimulationParams();
            app.initializeViews();
            app.initializeGUIcomponents();
            app.initializeSimulationComponents();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.mainView.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {999, 999};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {556, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: radialeccentricitydegsSlider
        function radialeccentricitydegsSliderUpdated(app, event)
            app.decodeRadialEccentricityDegsSlider(app.radialeccentricitydegsSlider.Value);
            app.updateRadialEccentricitySliderText();
            regenerateMosaicAndOptics(app, [], 0, 1);
        end

        % Value changed function: fieldofviewdegsSlider
        function sizeDegsSliderUpdated(app, event)
            app.decodeSizeDegsSlider(app.fieldofviewdegsSlider.Value);
            app.updateMosaicSizeSliderText();
            regenerateMosaicAndOptics(app, [], 0, 1);
        end

        % Value changing function: radialeccentricitydegsSlider
        function radialeccentricitySliderContinuousUpdate(app, event)
            app.decodeRadialEccentricityDegsSlider(event.Value);
            app.updateVisualFieldView();
        end

        % Value changed function: polareccentricitydegsSlider
        function polareccentricitydegsSliderUpdated(app, event)
            app.decodePolarEccentricityDegsSlider(app.polareccentricitydegsSlider.Value);
            regenerateMosaicAndOptics(app, [], 0,1);
        end

        % Value changing function: polareccentricitydegsSlider
        function polareccentricitySliderContinuousUpdate(app, event)
            app.decodePolarEccentricityDegsSlider(event.Value);
            app.updateVisualFieldView();
        end

        % Value changed function: w_minSpinner
        function minWavelengthChanged(app, event)
            % Update the minWavelength
            app.wavelengthMinValue = app.w_minSpinner.Value;
            
            % Update the wave property of the cone mosaic
            app.components.coneMosaic.wave = app.wavelengthMinValue : app.wavelengthStepSize: app.wavelengthMaxValue;
            % Recompute optics
            app.regenerateOptics();
            
            % No need to recompute optics - we just did it
            app.recomputeOptics = ~true;
        end

        % Value changed function: w_maxSpinner
        function maxWavelengthChanged(app, event)
            % Update the maxWavelength
            app.wavelengthMaxValue = app.w_maxSpinner.Value;
            
            % Update the wave property of the cone mosaic
            app.components.coneMosaic.wave = app.wavelengthMinValue : app.wavelengthStepSize: app.wavelengthMaxValue;
            % Recompute optics
            app.regenerateOptics();
            
            % No need to recompute optics - we just did it
            app.recomputeOptics = ~true;
        end

        % Value changed function: w_stepSpinner
        function stepWavelengthChanged(app, event)
            app.wavelengthStepSize = app.w_stepSpinner.Value;
            
            % Update the wave property of the cone mosaic
            app.components.coneMosaic.wave = app.wavelengthMinValue : app.wavelengthStepSize: app.wavelengthMaxValue;
            % Recompute optics
            app.regenerateOptics();
            
            % No need to recompute optics - we just did it
            app.recomputeOptics = false;
        end

        % Value changed function: PolanssubjectSpinner
        function PolansSubjectChanged(app, event)
            app.opticsPolansSubjectID = app.PolanssubjectSpinner.Value;
            
            % Recompute optics
            app.regenerateOptics();
            
            % Update displayed PSF
            app.updatePSFView();
            
            % No need to recompute optics - we just did it
            app.recomputeOptics = false;
        end

        % Value changed function: pupildiametermmSpinner
        function pupilDiameterMMChanged(app, event)
            app.opticsPupilDiameterMM = app.pupildiametermmSpinner.Value;
            
            % Recompute optics
            app.regenerateOptics();
            
            % Update displayed PSF
            app.updatePSFView();
            
            % No need to recompute optics - we just did it
            app.recomputeOptics = false;
        end

        % Value changed function: cLSpinner
        function stimLcontChanged(app, event)
            app.stimLcontrast = app.cLSpinner.Value;
        end

        % Value changed function: cMSpinner
        function stimMcontChanged(app, event)
            app.stimMcontrast = app.cMSpinner.Value;
        end

        % Value changed function: cSSpinner
        function stimScontChanged(app, event)
            app.stimScontrast = app.cSSpinner.Value;
        end

        % Value changed function: flashmsecSpinner
        function stimDurationChanged(app, event)
            app.stimDurationMsec = app.flashmsecSpinner.Value;
        end

        % Button pushed function: generateButton
        function stimRefreshgenerateButtonPushed(app, event)
            app.refreshStimulus();
        end

        % Value changed function: sizedegsSpinner
        function stimSizeDegsChanged(app, event)
            app.stimSizeDegs = app.sizedegsSpinner.Value;
            app.refreshStimulus();
        end

        % Value changed function: domainSwitch
        function mosaicDomainChanged(app, event)
            app.mosaicDomain = app.domainSwitch.Value;
            app.updateMosaicView();
        end

        % Value changing function: fieldofviewdegsSlider
        function sizeDegsSliderContinuousUpdate(app, event)
            app.decodeSizeDegsSlider(event.Value);
            app.updateMosaicSizeSliderText();
        end

        % Value changed function: spatialFrequencySpinner
        function stimSpatialFrequencyChanged(app, event)
            app.stimSpatialFrequencyCyclesPerDeg = app.spatialFrequencySpinner.Value;
            app.refreshStimulus();
        end

        % Value changed function: stimulusOverlaySwitch
        function stimulusOverlaySwitchstimulusOverlayChanged(app, event)
            app.overlayMosaicOnStimulus = app.stimulusOverlaySwitch.Value;
            app.updateMosaicView();
        end

        % Value changed function: activationSwitch
        function activationSwitchChanged(app, event)
            app.mosaicDisplayMode = app.activationSwitch.Value;
            
            if strcmp(app.mosaicDisplayMode, 'activation')
                app.recomputeMosaicResponseToDemoStimulusScene();
            end
            
            app.updateMosaicView();
        end

        % Value changed function: envelopeDropDown
        function stimEnvelopeChanged(app, event)
            app.stimEnvelope = app.envelopeDropDown.Value;
            app.refreshStimulus();
        end

        % Value changed function: slope_minSpinner
        function minPsychometricSlopeChanged(app, event)
            app.psychometricFunctionMinSlope = app.slope_minSpinner.Value;
            app.updatePsychometricFunctionView();
        end

        % Value changed function: slope_maxSpinner
        function maxPsychometricSlopeChanged(app, event)
            app.psychometricFunctionMaxSlope = app.slope_maxSpinner.Value;
            app.updatePsychometricFunctionView();
        end

        % Value changed function: log10C_minSpinner
        function psychometricFunctionMinLogThresholdChanged(app, event)
            app.psychometricFunctionMinLogThreshold = app.log10C_minSpinner.Value;
            app.updatePsychometricFunctionView();
        end

        % Value changed function: log10C_maxSpinner
        function psychometricFunctionMaxLogThresholdChanged(app, event)
            app.psychometricFunctionMaxLogThreshold = app.log10C_maxSpinner.Value;
            app.updatePsychometricFunctionView();
        end

        % Value changed function: sfMinSpinner
        function minSpatialFrequencyChanged(app, event)
            app.csfMinSpatialFrequency = app.sfMinSpinner.Value;
            app.updateCSFView();
        end

        % Value changed function: sfMaxSpinner
        function maxSpatialFrequencyChanged(app, event)
            app.csfMaxSpatialFrequency = app.sfMaxSpinner.Value;
            app.updateCSFView();
        end

        % Value changed function: sfSamplesSpinner
        function samplesSpatialFrequencyChanged(app, event)
            app.csfSamplesSpatialFrequency = app.sfSamplesSpinner.Value;
            app.updateCSFView();
        end

        % Button pushed function: computeCSFButton
        function computeCSFButtonPressed(app, event)
            % Compute CSF with current settings
            sfSupport = app.decodeCSFsupport();
            
            % Create neural response engine with optics and cone mosaics
            % that are already generated in this app. This is different
            % than what we have been doing so far, where we pass parameters
            % and the code generates the optics and the mosaic.
            
            % WORK ON IMPLEMENTING THIS
            neuralParams = nrePhotopigmentExcitationsCmosaicSingleShot(...
                'cMosaic', app.components.coneMosaic, ...
                'optics', app.components.optics);
            
            % So we do not pass parameters, 
            %neuralParams.opticsParams.PolansSubject = 
            %neuralParams.opticsParams.pupilDiameterMM = 
            %neuralParams.coneMosaicParams.sizeDegs = 
            %neuralParams.coneMosaicParams.eccDegs =  
            %neuralParams.coneMosaicParams.timeIntegrationSeconds =   

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainView = uifigure('Visible', 'off');
            app.mainView.Name = 'Interactive ISETBio CSF Generator, Written by N.P. Cottaris, ISETBio Team, 2021';
            app.mainView.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.mainView);
            app.LeftPanel = uipanel(app.GridLayout);
            app.retinalpatchPanel = uipanel(app.LeftPanel);
            app.retinalpatchPanel.Title = 'retinal patch';
            app.radialeccentricitydegsSliderLabel = uilabel(app.retinalpatchPanel);
            app.radialeccentricitydegsSliderLabel.Tag = 'eccntricitySliderText';
            app.radialeccentricitydegsSliderLabel.Text = 'radial eccentricity (degs)';
            app.radialeccentricitydegsSlider = uislider(app.retinalpatchPanel);
            app.radialeccentricitydegsSlider.Limits = [0 8];
            app.radialeccentricitydegsSlider.MajorTicks = [0 1 2 3 4 5 6 7 8];
            app.radialeccentricitydegsSlider.MajorTickLabels = {'0', '.15', '.3', '.6', '1.2', '2.5', '5', '10', '20'};
            app.radialeccentricitydegsSlider.ValueChangedFcn = createCallbackFcn(app, @radialeccentricitydegsSliderUpdated, true);
            app.radialeccentricitydegsSlider.ValueChangingFcn = createCallbackFcn(app, @radialeccentricitySliderContinuousUpdate, true);
            app.radialeccentricitydegsSlider.MinorTicks = [];
            app.radialeccentricitydegsSlider.Value = 1;
            app.polareccentricitydegsSlider = uiknob(app.retinalpatchPanel, 'continuous');
            app.polareccentricitydegsSlider.Limits = [0 360];
            app.polareccentricitydegsSlider.MajorTicks = [0 90 180 270 360];
            app.polareccentricitydegsSlider.MajorTickLabels = {'0', '90 ', '180 ', '270 ', '360', ''};
            app.polareccentricitydegsSlider.ValueChangedFcn = createCallbackFcn(app, @polareccentricitydegsSliderUpdated, true);
            app.polareccentricitydegsSlider.ValueChangingFcn = createCallbackFcn(app, @polareccentricitySliderContinuousUpdate, true);
            app.polareccentricitydegsSlider.MinorTicks = [];
            app.polareccentricitydegsSlider.Value = 360;
            app.fieldofviewdegsSliderLabel = uilabel(app.retinalpatchPanel);
            app.fieldofviewdegsSliderLabel.Text = 'field of view (degs)';
            app.fieldofviewdegsSlider = uislider(app.retinalpatchPanel);
            app.fieldofviewdegsSlider.Limits = [1 8];
            app.fieldofviewdegsSlider.MajorTicks = [1 2 3 4 5 6 7 8];
            app.fieldofviewdegsSlider.MajorTickLabels = {'0.07 ', '0.15 ', '0.3 ', '0.6 ', '1.2', ' 2.5', ' 5', '10'};
            app.fieldofviewdegsSlider.ValueChangedFcn = createCallbackFcn(app, @sizeDegsSliderUpdated, true);
            app.fieldofviewdegsSlider.ValueChangingFcn = createCallbackFcn(app, @sizeDegsSliderContinuousUpdate, true);
            app.fieldofviewdegsSlider.MinorTicks = [];
            app.fieldofviewdegsSlider.Value = 4;
            app.eyeSwitch = uiswitch(app.retinalpatchPanel, 'slider');
            app.eyeSwitch.Items = {'left eye', 'right eye'};
            app.eyeSwitch.Value = 'left eye';
            app.retinalmagnificationfactorumdegEditFieldLabel = uilabel(app.retinalpatchPanel);
            app.retinalmagnificationfactorumdegEditFieldLabel.Text = 'retinal magnification factor (um/deg) ';
            app.retinalmagnificationfactorumdegEditField = uieditfield(app.retinalpatchPanel, 'numeric');
            app.retinalmagnificationfactorumdegEditField.Editable = 'off';
            app.retinalmagnificationfactorumdegEditField.Tooltip = {'retinal magnification factor at current eccentricity'};
            app.visualFieldView = uiaxes(app.retinalpatchPanel);
            xlabel(app.visualFieldView, 'X')
            ylabel(app.visualFieldView, 'Y')
            zlabel(app.visualFieldView, 'Z')
            app.tasksPanel = uipanel(app.LeftPanel);
            app.tasksPanel.Title = 'tasks';
            app.computeCSFButton = uibutton(app.tasksPanel, 'push');
            app.computeCSFButton.ButtonPushedFcn = createCallbackFcn(app, @computeCSFButtonPressed, true);
            app.computeCSFButton.Text = 'compute CSF';
            app.exportscriptButton = uibutton(app.tasksPanel, 'push');
            app.exportscriptButton.Text = 'export script';
            app.exportdataButton = uibutton(app.tasksPanel, 'push');
            app.exportdataButton.Text = 'export data';
            app.exportfigsButton = uibutton(app.tasksPanel, 'push');
            app.exportfigsButton.Text = 'export figs';
            app.RightPanel = uipanel(app.GridLayout);
            app.psychometricfunctionestimationPanel = uipanel(app.RightPanel);
            app.psychometricfunctionestimationPanel.Title = 'psychometric function estimation';
            app.slope_minSpinnerLabel = uilabel(app.psychometricfunctionestimationPanel);
            app.slope_minSpinnerLabel.Text = 'slope_min ';
            app.slope_minSpinner = uispinner(app.psychometricfunctionestimationPanel);
            app.slope_minSpinner.Limits = [1 100];
            app.slope_minSpinner.ValueChangedFcn = createCallbackFcn(app, @minPsychometricSlopeChanged, true);
            app.slope_minSpinner.Value = 5;
            app.slope_maxSpinnerLabel = uilabel(app.psychometricfunctionestimationPanel);
            app.slope_maxSpinnerLabel.Text = 'slope_max ';
            app.slope_maxSpinner = uispinner(app.psychometricfunctionestimationPanel);
            app.slope_maxSpinner.Step = 10;
            app.slope_maxSpinner.Limits = [10 10000];
            app.slope_maxSpinner.ValueChangedFcn = createCallbackFcn(app, @maxPsychometricSlopeChanged, true);
            app.slope_maxSpinner.Value = 50;
            app.classifiertrainingtrialsSpinnerLabel = uilabel(app.psychometricfunctionestimationPanel);
            app.classifiertrainingtrialsSpinnerLabel.Text = {'classifier training trials'; ''};
            app.classifiertrainingtrialsSpinner = uispinner(app.psychometricfunctionestimationPanel);
            app.classifiertrainingtrialsSpinner.Limits = [1 100000];
            app.classifiertrainingtrialsSpinner.Value = 256;
            app.performancetrialsSpinnerLabel = uilabel(app.psychometricfunctionestimationPanel);
            app.performancetrialsSpinnerLabel.Text = {'performance trials'; ''};
            app.performancetrialsSpinner = uispinner(app.psychometricfunctionestimationPanel);
            app.performancetrialsSpinner.Limits = [1 100000];
            app.performancetrialsSpinner.Value = 128;
            app.slope_deltaSpinnerLabel = uilabel(app.psychometricfunctionestimationPanel);
            app.slope_deltaSpinnerLabel.Text = 'slope_delta ';
            app.slope_deltaSpinner = uispinner(app.psychometricfunctionestimationPanel);
            app.slope_deltaSpinner.Limits = [1 100];
            app.slope_deltaSpinner.Value = 2.5;
            app.log10C_minSpinnerLabel = uilabel(app.psychometricfunctionestimationPanel);
            app.log10C_minSpinnerLabel.Text = 'log10C_min ';
            app.log10C_minSpinner = uispinner(app.psychometricfunctionestimationPanel);
            app.log10C_minSpinner.Step = 0.1;
            app.log10C_minSpinner.Limits = [-4 0];
            app.log10C_minSpinner.ValueChangedFcn = createCallbackFcn(app, @psychometricFunctionMinLogThresholdChanged, true);
            app.log10C_minSpinner.Value = -2.4;
            app.log10C_maxSpinnerLabel = uilabel(app.psychometricfunctionestimationPanel);
            app.log10C_maxSpinnerLabel.Text = 'log10C_max ';
            app.log10C_maxSpinner = uispinner(app.psychometricfunctionestimationPanel);
            app.log10C_maxSpinner.Step = 0.1;
            app.log10C_maxSpinner.Limits = [-4 0];
            app.log10C_maxSpinner.ValueChangedFcn = createCallbackFcn(app, @psychometricFunctionMaxLogThresholdChanged, true);
            app.log10C_deltaSpinnerLabel = uilabel(app.psychometricfunctionestimationPanel);
            app.log10C_deltaSpinnerLabel.Text = 'log10C_delta ';
            app.log10C_deltaSpinner = uispinner(app.psychometricfunctionestimationPanel);
            app.log10C_deltaSpinner.Step = 0.005;
            app.log10C_deltaSpinner.Limits = [0.01 0.1];
            app.log10C_deltaSpinner.Value = 0.02;
            app.psychometricFunctionView = uiaxes(app.psychometricfunctionestimationPanel);
            xlabel(app.psychometricFunctionView, 'X')
            ylabel(app.psychometricFunctionView, 'Y')
            zlabel(app.psychometricFunctionView, 'Z')
            app.conemosaicPanel = uipanel(app.RightPanel);
            app.conemosaicPanel.Title = 'cone mosaic';
            app.Label = uilabel(app.conemosaicPanel);
            app.Label.Text = '';
            app.eccvaryingmacularpigmentCheckBox = uicheckbox(app.conemosaicPanel);
            app.eccvaryingmacularpigmentCheckBox.Text = 'ecc-varying macular pigment';
            app.eccvaryingaperturelightgatheringCheckBox = uicheckbox(app.conemosaicPanel);
            app.eccvaryingaperturelightgatheringCheckBox.Text = 'ecc-varying aperture (light gathering)';
            app.eccvaryingapertureblurCheckBox = uicheckbox(app.conemosaicPanel);
            app.eccvaryingapertureblurCheckBox.Text = 'ecc-varying aperture blur';
            app.domainSwitch = uiswitch(app.conemosaicPanel, 'slider');
            app.domainSwitch.Items = {'deg', 'um'};
            app.domainSwitch.ValueChangedFcn = createCallbackFcn(app, @mosaicDomainChanged, true);
            app.domainSwitch.Value = 'deg';
            app.integrationtimemsecLabel = uilabel(app.conemosaicPanel);
            app.integrationtimemsecLabel.Text = 'integration time (msec) ';
            app.integrationtimemsecSpinner = uispinner(app.conemosaicPanel);
            app.integrationtimemsecSpinner.Limits = [1 1000];
            app.integrationtimemsecSpinner.Value = 5;
            app.Switch_3Label = uilabel(app.conemosaicPanel);
            app.Switch_3Label.Text = '';
            app.psfGridOverlaySwitch = uiswitch(app.conemosaicPanel, 'slider');
            app.psfGridOverlaySwitch.Items = {'0', 'psf grid overlay'};
            app.psfGridOverlaySwitch.Value = '0';
            app.Label_2 = uilabel(app.conemosaicPanel);
            app.Label_2.Text = '';
            app.stimulusOverlaySwitch = uiswitch(app.conemosaicPanel, 'slider');
            app.stimulusOverlaySwitch.Items = {'0', 'stimulus overlay'};
            app.stimulusOverlaySwitch.ValueChangedFcn = createCallbackFcn(app, @stimulusOverlaySwitchstimulusOverlayChanged, true);
            app.stimulusOverlaySwitch.Value = '0';
            app.eccvaryingmacularpigmentdynamicCheckBox = uicheckbox(app.conemosaicPanel);
            app.eccvaryingmacularpigmentdynamicCheckBox.Text = 'ecc-varying macular pigment (dynamic)';
            app.Label_3 = uilabel(app.conemosaicPanel);
            app.Label_3.Text = '';
            app.activationSwitch = uiswitch(app.conemosaicPanel, 'slider');
            app.activationSwitch.Items = {'cones', 'activation'};
            app.activationSwitch.ValueChangedFcn = createCallbackFcn(app, @activationSwitchChanged, true);
            app.activationSwitch.Value = 'cones';
            app.mosaicView = uiaxes(app.conemosaicPanel);
            app.opticsPanel = uipanel(app.RightPanel);
            app.opticsPanel.Title = 'optics';
            app.conesSwitch = uiswitch(app.opticsPanel, 'slider');
            app.conesSwitch.Items = {'0', 'cones overlay'};
            app.conesSwitch.Value = '0';
            app.PolanssubjectLabel = uilabel(app.opticsPanel);
            app.PolanssubjectLabel.Text = 'Polans subject';
            app.PolanssubjectSpinner = uispinner(app.opticsPanel);
            app.PolanssubjectSpinner.Limits = [1 10];
            app.PolanssubjectSpinner.ValueChangedFcn = createCallbackFcn(app, @PolansSubjectChanged, true);
            app.PolanssubjectSpinner.Value = 10;
            app.eccvaryingopticalblurgridCheckBox = uicheckbox(app.opticsPanel);
            app.eccvaryingopticalblurgridCheckBox.Text = 'ecc-varying optical blur (grid)';
            app.xnodesSpinnerLabel = uilabel(app.opticsPanel);
            app.xnodesSpinnerLabel.Text = 'x nodes ';
            app.xnodesSpinner = uispinner(app.opticsPanel);
            app.xnodesSpinner.Limits = [1 9];
            app.xnodesSpinner.Value = 1;
            app.ynodesSpinnerLabel = uilabel(app.opticsPanel);
            app.ynodesSpinnerLabel.Text = 'y nodes ';
            app.ynodesSpinner = uispinner(app.opticsPanel);
            app.ynodesSpinner.Limits = [1 9];
            app.ynodesSpinner.Value = 1;
            app.pupildiametermmLabel = uilabel(app.opticsPanel);
            app.pupildiametermmLabel.Text = 'pupil diameter (mm) ';
            app.pupildiametermmSpinner = uispinner(app.opticsPanel);
            app.pupildiametermmSpinner.Step = 0.5;
            app.pupildiametermmSpinner.Limits = [1 4];
            app.pupildiametermmSpinner.ValueChangedFcn = createCallbackFcn(app, @pupilDiameterMMChanged, true);
            app.pupildiametermmSpinner.Value = 3;
            app.psfView = uiaxes(app.opticsPanel);
            xlabel(app.psfView, 'X')
            ylabel(app.psfView, 'Y')
            zlabel(app.psfView, 'Z')
            app.stimulusPanel = uipanel(app.RightPanel);
            app.stimulusPanel.Title = 'stimulus';
            app.cLSpinnerLabel = uilabel(app.stimulusPanel);
            app.cLSpinnerLabel.Text = 'L-cont. (%) ';
            app.cMSpinner = uispinner(app.stimulusPanel);
            app.cMSpinner.Step = 0.5;
            app.cMSpinner.Limits = [-100 100];
            app.cMSpinner.ValueChangedFcn = createCallbackFcn(app, @stimMcontChanged, true);
            app.cMSpinner.Value = -30;
            app.cSSpinner = uispinner(app.stimulusPanel);
            app.cSSpinner.Step = 0.5;
            app.cSSpinner.Limits = [-100 100];
            app.cSSpinner.ValueChangedFcn = createCallbackFcn(app, @stimScontChanged, true);
            app.cSSpinner.Value = 85;
            app.cLSpinner = uispinner(app.stimulusPanel);
            app.cLSpinner.Step = 0.5;
            app.cLSpinner.Limits = [-100 100];
            app.cLSpinner.ValueChangedFcn = createCallbackFcn(app, @stimLcontChanged, true);
            app.cLSpinner.Value = -30;
            app.McontLabel = uilabel(app.stimulusPanel);
            app.McontLabel.Text = 'M-cont. (%) ';
            app.ScontLabel = uilabel(app.stimulusPanel);
            app.ScontLabel.Text = 'S-cont. (%) ';
            app.w_minSpinner = uispinner(app.stimulusPanel);
            app.w_minSpinner.Step = 10;
            app.w_minSpinner.Limits = [300 800];
            app.w_minSpinner.ValueChangedFcn = createCallbackFcn(app, @minWavelengthChanged, true);
            app.w_minSpinner.Value = 400;
            app.w_maxSpinner = uispinner(app.stimulusPanel);
            app.w_maxSpinner.Step = 10;
            app.w_maxSpinner.Limits = [400 800];
            app.w_maxSpinner.ValueChangedFcn = createCallbackFcn(app, @maxWavelengthChanged, true);
            app.w_maxSpinner.Value = 600;
            app.w_stepSpinner = uispinner(app.stimulusPanel);
            app.w_stepSpinner.Limits = [1 100];
            app.w_stepSpinner.ValueChangedFcn = createCallbackFcn(app, @stepWavelengthChanged, true);
            app.w_stepSpinner.Value = 50;
            app.w_minSpinnerLabel = uilabel(app.stimulusPanel);
            app.w_minSpinnerLabel.Text = 'w_min (nm) ';
            app.w_maxSpinnerLabel = uilabel(app.stimulusPanel);
            app.w_maxSpinnerLabel.Text = 'w_max (nm) ';
            app.w_stepSpinnerLabel = uilabel(app.stimulusPanel);
            app.w_stepSpinnerLabel.Text = 'w_step (nm) ';
            app.flashmsecSpinnerLabel = uilabel(app.stimulusPanel);
            app.flashmsecSpinnerLabel.Text = 'flash (msec) ';
            app.flashmsecSpinner = uispinner(app.stimulusPanel);
            app.flashmsecSpinner.Step = 10;
            app.flashmsecSpinner.Limits = [10 1000];
            app.flashmsecSpinner.ValueChangedFcn = createCallbackFcn(app, @stimDurationChanged, true);
            app.flashmsecSpinner.Value = 100;
            app.sizedegsSpinnerLabel = uilabel(app.stimulusPanel);
            app.sizedegsSpinnerLabel.Text = 'size (degs) ';
            app.sizedegsSpinner = uispinner(app.stimulusPanel);
            app.sizedegsSpinner.Step = 0.5;
            app.sizedegsSpinner.Limits = [0.05 40];
            app.sizedegsSpinner.ValueChangedFcn = createCallbackFcn(app, @stimSizeDegsChanged, true);
            app.sizedegsSpinner.Value = 1;
            app.generateButton = uibutton(app.stimulusPanel, 'push');
            app.generateButton.ButtonPushedFcn = createCallbackFcn(app, @stimRefreshgenerateButtonPushed, true);
            app.generateButton.Text = 'generate';
            app.sfcdegLabel = uilabel(app.stimulusPanel);
            app.sfcdegLabel.Text = 'sf (c/deg) ';
            app.spatialFrequencySpinner = uispinner(app.stimulusPanel);
            app.spatialFrequencySpinner.Step = 0.5;
            app.spatialFrequencySpinner.Limits = [0 60];
            app.spatialFrequencySpinner.ValueChangedFcn = createCallbackFcn(app, @stimSpatialFrequencyChanged, true);
            app.spatialFrequencySpinner.Value = 3;
            app.stimulusGenerationStatusReport = uieditfield(app.stimulusPanel, 'text');
            app.stimulusGenerationStatusReport.Editable = 'off';
            app.stimulusGenerationStatusReport.Value = 'OK';
            app.yPosdegsSpinnerLabel = uilabel(app.stimulusPanel);
            app.yPosdegsSpinnerLabel.Text = 'yPos (degs) ';
            app.yPosdegsSpinner = uispinner(app.stimulusPanel);
            app.xPosdegsSpinner_2Label = uilabel(app.stimulusPanel);
            app.xPosdegsSpinner_2Label.Text = 'xPos (degs) ';
            app.xPosdegsSpinner_2 = uispinner(app.stimulusPanel);
            app.envelopeLabel = uilabel(app.stimulusPanel);
            app.envelopeLabel.Text = 'envelope ';
            app.envelopeDropDown = uidropdown(app.stimulusPanel);
            app.envelopeDropDown.Items = {'rect', 'disk', 'soft'};
            app.envelopeDropDown.ValueChangedFcn = createCallbackFcn(app, @stimEnvelopeChanged, true);
            app.envelopeDropDown.Value = 'rect';
            app.Label_4 = uilabel(app.stimulusPanel);
            app.Label_4.Text = '';
            app.Switch = uiswitch(app.stimulusPanel, 'slider');
            app.Switch.Items = {'mosaic centered', ''};
            app.Switch.Value = '';
            app.stimulusView = uiaxes(app.stimulusPanel);
            app.stimulusView.XTick = [];
            app.stimulusView.XTickLabel = '';
            app.stimulusView.YTick = [];
            app.stimulusView.YTickLabel = '';
            app.contrastsensitivityfunctionPanel = uipanel(app.RightPanel);
            app.contrastsensitivityfunctionPanel.Title = 'contrast sensitivity function';
            app.sfSamplesSpinnerLabel = uilabel(app.contrastsensitivityfunctionPanel);
            app.sfSamplesSpinnerLabel.Text = 'samples';
            app.sfSamplesSpinner = uispinner(app.contrastsensitivityfunctionPanel);
            app.sfSamplesSpinner.Limits = [1 16];
            app.sfSamplesSpinner.ValueChangedFcn = createCallbackFcn(app, @samplesSpatialFrequencyChanged, true);
            app.sfSamplesSpinner.Value = 6;
            app.sfMaxLabel = uilabel(app.contrastsensitivityfunctionPanel);
            app.sfMaxLabel.Text = 'sf_max ';
            app.sfMaxSpinner = uispinner(app.contrastsensitivityfunctionPanel);
            app.sfMaxSpinner.Step = 5;
            app.sfMaxSpinner.Limits = [10 60];
            app.sfMaxSpinner.ValueChangedFcn = createCallbackFcn(app, @maxSpatialFrequencyChanged, true);
            app.sfMaxSpinner.Value = 30;
            app.sfMinLabel = uilabel(app.contrastsensitivityfunctionPanel);
            app.sfMinLabel.Text = 'sf_min ';
            app.sfMinSpinner = uispinner(app.contrastsensitivityfunctionPanel);
            app.sfMinSpinner.Step = 0.5;
            app.sfMinSpinner.Limits = [1 10];
            app.sfMinSpinner.ValueChangedFcn = createCallbackFcn(app, @minSpatialFrequencyChanged, true);
            app.sfMinSpinner.Value = 2;
            app.csfView = uiaxes(app.contrastsensitivityfunctionPanel);
            xlabel(app.csfView, 'X')
            ylabel(app.csfView, 'Y')
            zlabel(app.csfView, 'Z')
            app.fixationaleyemovementsPanel = uipanel(app.RightPanel);
            app.fixationaleyemovementsPanel.Title = 'fixational eye movements';
            app.componentsLabel = uilabel(app.fixationaleyemovementsPanel);
            app.componentsLabel.Text = 'components ';
            app.componentsDropDown = uidropdown(app.fixationaleyemovementsPanel);
            app.componentsDropDown.Items = {'none', 'drift', 'drift + uSaccades'};
            app.componentsDropDown.Value = 'none';
            % Show the figure after all components are created
            app.mainView.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ISETBioCSFGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainView)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainView)
        end
    end
end