classdef RGCSFtunningSimulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RGCspatialfrequencytuningsimulatorNPCottarisISETBioTeamUIFigure  matlab.ui.Figure
        surroundradiusdegsSliderLabel  matlab.ui.control.Label
        surroundradiusdegsSlider       matlab.ui.control.Slider
        centersurroundpeaksensitivitySliderLabel  matlab.ui.control.Label
        centersurroundpeaksensitivitySlider  matlab.ui.control.Slider
        subjectPolans2015SpinnerLabel  matlab.ui.control.Label
        subjectPolans2015Spinner       matlab.ui.control.Spinner
        xdegsSpinnerLabel              matlab.ui.control.Label
        xdegsSpinner                   matlab.ui.control.Spinner
        ydegsSpinnerLabel              matlab.ui.control.Label
        ydegsSpinner                   matlab.ui.control.Spinner
        diffractionlimitedopticsCheckBox  matlab.ui.control.CheckBox
        surroundoffsetLabel            matlab.ui.control.Label
        integratedsensitivtysurroundcenterEditField  matlab.ui.control.NumericEditField
        integratedsensitivitysurroundcenterLabel  matlab.ui.control.Label
        autoscalesurroundweightsCheckBox  matlab.ui.control.CheckBox
        receptiveFieldView             matlab.ui.control.UIAxes
        SFTuningView                   matlab.ui.control.UIAxes
        psfInsetView                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mosaicEcc = [0.0 0];
        targetConeEcc = [0.0 0];
        
        diffractionLimitedOptics
        subjectRank
        surroundGain
        surroundRadius
        surroundOffsetXDegs
        surroundOffsetYDegs
        surroundOutlineX
        surroundOutlineY
        
        autoScaleSurroundWeights
        centerWeights
        surroundWeights
        targetConeIndex
        
        examinedSFs
        SFTuning
        coneMosaicSpatiotemporalActivation
        baserateActivation
        temporalSupportSeconds
        coneMosaic
        
        psfIsStale 
        psf
        psfSupportDegsX
        psfSupportDegsY
    end
    
    methods (Access = private)
        
        function loadSubjectData(app)
            
            
            dataSourceDir = strrep(isetRootPath, 'isettools','demoapps/RGCSFTuningSimulator/Resources');
            if (app.diffractionLimitedOptics)
                dataSourceFile = sprintf('PolansSubject%d_ecc_%2.1f_%2.1f_ConeMosaicDriftingSinewaveResponses.mat', 0, app.mosaicEcc(1), app.mosaicEcc(2));
            else
                dataSourceFile = sprintf('PolansSubject%d_ecc_%2.1f_%2.1f_ConeMosaicDriftingSinewaveResponses.mat', app.subjectRank, app.mosaicEcc(1), app.mosaicEcc(2));
            end
            
            load(fullfile(dataSourceDir, dataSourceFile), ...
                'examinedSFs', ...
                'coneMosaicSpatiotemporalActivation', 'baserateActivation', ...
                'temporalSupportSeconds', 'theConeMosaic', 'psfData');
            app.examinedSFs = examinedSFs;
            app.coneMosaicSpatiotemporalActivation = coneMosaicSpatiotemporalActivation;
            app.baserateActivation = baserateActivation;
            app.temporalSupportSeconds = temporalSupportSeconds;
            app.coneMosaic = theConeMosaic;
            
            % Extract the PSF
            app.psf = psfData.psf;
            app.psfSupportDegsX = psfData.psfSupportDegsX;
            app.psfSupportDegsY = psfData.psfSupportDegsY;
            app.psfIsStale = true;
        end
        
        function visualizeWeights(app)
            rf = app.surroundWeights;
            rf(app.centerWeights == 1) = 1;
    
            % Part of the mosaic visualized in the inset
            visualizedFraction = 0.2;
            w = app.coneMosaic.sizeDegs(1);
            domainVisualizationLims(1:2) = app.coneMosaic.eccentricityDegs(1) + w/2*[-1 1];
            domainVisualizationLims(3:4) = app.coneMosaic.eccentricityDegs(2) + w/2*[-1 1];
            domainVisualizationLimsInset(1:2) = app.coneMosaic.eccentricityDegs(1) + visualizedFraction*w/2*[-1 1];
            domainVisualizationLimsInset(3:4) = app.coneMosaic.eccentricityDegs(2) + visualizedFraction*w/2*[-1 1];
            domainVisualizationTicks = struct('x',  [-0.1 0 0.1]+ app.coneMosaic.eccentricityDegs(1), 'y', [-0.1 0 0.1]+ app.coneMosaic.eccentricityDegs(2));
            
            if (app.autoScaleSurroundWeights)
                activationRange = abs(min(rf(:)))*[-1 1];
            else
                activationRange = 0.01*[-1 1];
            end
  
            app.coneMosaic.visualize('figureHandle', app.RGCspatialfrequencytuningsimulatorNPCottarisISETBioTeamUIFigure, 'axesHandle', app.receptiveFieldView, ...
                      'activation', rf, ...
                      'activationColorMap', brewermap(1024, '*RdBu'), ...
                      'activationRange', activationRange, ...
                      'domain', 'degrees', ...
                      'domainVisualizationLimits', domainVisualizationLims, ...
                      'domainVisualizationTicks', domainVisualizationTicks, ...
                      'plotTitle', ' ');
            hold(app.receptiveFieldView, 'on')
            plot(app.receptiveFieldView, app.surroundOutlineX, app.surroundOutlineY, 'b--', 'LineWidth', 1.5); 


            if (app.psfIsStale)
                domainVisualizationTicks = struct('x',  [nan], 'y', [nan]);
                % Inset showing a high-res version of the mosaic and the PSF
                app.coneMosaic.visualize('figureHandle', app.RGCspatialfrequencytuningsimulatorNPCottarisISETBioTeamUIFigure, ...
                    'axesHandle', app.psfInsetView, ...
                    'domain', 'degrees', ...
                    'domainVisualizationLimits', domainVisualizationLimsInset, ...
                    'domainVisualizationTicks', domainVisualizationTicks, ...
                    'labelCones', ~true, ...
                    'visualizedConeAperture', 'lightCollectingArea', ...
                    'noYLabel', true, ...
                    'noXLabel', true, ...
                    'backgroundColor', [0 0 0], ...
                    'plotTitle', ' ');
                
                hold(app.psfInsetView, 'on');
                cmap = brewermap(1024,'reds');
                alpha = 0.4;
        
                contourLineColor = [0.0 0.0 0.0];
                cMosaic.semiTransparentContourPlot(app.psfInsetView, app.psfSupportDegsX, app.psfSupportDegsY, app.psf, ...
                    0.02:0.1:0.95, cmap, alpha, contourLineColor);
                
                app.psfIsStale = false;
            end
            
        end
        
        
        function computeWeights(app)
            % Find the cone at the target eccentricity
            r = sqrt(sum((bsxfun(@minus, app.coneMosaic.coneRFpositionsDegs, app.targetConeEcc)).^2,2));
            [~,app.targetConeIndex] = min(r);

            % Surround radius estimate based on eccentricity
            %mRGCeccDegs = sqrt(sum(theConeMosaic.coneRFpositionsDegs(targetConeIndex,:).^2,2));
            %surroundCharacteristicRadiusFromCroner = ...
            %    RGCmodels.CronerKaplan.constants.surroundCharacteristicRadiusFromFitToPandMcells(mRGCeccDegs);

            % Surround weights (Gaussian profile)
            surroundPos = app.coneMosaic.coneRFpositionsDegs(app.targetConeIndex,:) + ...
                         [app.surroundOffsetXDegs app.surroundOffsetYDegs];
            
            app.surroundOutlineX = surroundPos(1) + app.surroundRadius*cosd(0:10:360);
            app.surroundOutlineY = surroundPos(2) + app.surroundRadius*sind(0:10:360);
            
            d = bsxfun(@minus, app.coneMosaic.coneRFpositionsDegs, surroundPos);
            d = sqrt(sum(d.^2,2));
            if (app.surroundRadius > 0)
                surroundWeights = exp(-(d/app.surroundRadius).^2);
                % If weight < 5%, zero it
                surroundWeights(surroundWeights<1/100) = 0;

                % Scale so as to achieve desired peak sensitivity ratio
                maxW = max(surroundWeights(:));
                surroundWeights = surroundWeights / maxW;
            else 
                surroundWeights = 0 * d;
            end
    
            
            app.surroundWeights = -surroundWeights * app.surroundGain;


            integratedConeRatios = [1/sum(abs(surroundWeights(:))) 0.466];

            % Center weights
            app.centerWeights = 0 * app.surroundWeights;
            app.centerWeights(app.targetConeIndex) = 1; 
            
            % Sum integrated center input (centerWeights weighted by cone area aperture.^2)
            apertureAreas = app.coneMosaic.coneRFspacingsDegs.^2;
            integratedCenterInput = dot(app.centerWeights, apertureAreas);
            integratedSurroundInput = dot(abs(app.surroundWeights), apertureAreas);
            app.integratedsensitivtysurroundcenterEditField.Value = integratedSurroundInput/integratedCenterInput;
        end
        
        function computeSFTuning(app)
             for iSF = 1:numel(app.examinedSFs) 
                 xtActivation = (squeeze(app.coneMosaicSpatiotemporalActivation(iSF,:,:)))';
                 mRGCresponse = 0*app.temporalSupportSeconds;
                 for t = 1:size(xtActivation,2)
                    % center - integrated surround
                    mRGCresponse(t) = xtActivation(app.targetConeIndex,t) + dot(app.surroundWeights, squeeze(xtActivation(:,t)),1);
                 end
                 app.SFTuning(iSF) = max(mRGCresponse)-min(mRGCresponse);
             end
        end


        function plotSFTuning(app)
            if (max(app.SFTuning)>1000)
                yTicks = round([0 0.25*max(app.SFTuning) 0.5*max(app.SFTuning) 0.75*max(app.SFTuning) max(app.SFTuning)]/100)*100;
            elseif (max(app.SFTuning)>100)
                yTicks = round([0 0.25*max(app.SFTuning) 0.5*max(app.SFTuning) 0.75*max(app.SFTuning) max(app.SFTuning)]/10)*10;
            elseif (max(app.SFTuning)>10)
                yTicks = round([0 0.25*max(app.SFTuning) 0.5*max(app.SFTuning) 0.75*max(app.SFTuning) max(app.SFTuning)]);
            else
                yTicks = [0 0.25*max(app.SFTuning) 0.5*max(app.SFTuning) 0.75*max(app.SFTuning) max(app.SFTuning)];
            end
            
            plot(app.SFTuningView, app.examinedSFs, app.SFTuning, 'ro-', 'LineWidth', 1.5, 'MarkerSize', 16, 'MarkerFaceColor', [1 0.5 0.5]);
            set(app.SFTuningView, 'YLim', [0 max(app.SFTuning)], 'YTick', yTicks)
        end
        
        function initializeViews(app)
            
            % Do not show the interactions toolbax
            app.SFTuningView.Toolbar.Visible = 'off';
            app.receptiveFieldView.Toolbar.Visible = 'off';
            app.psfInsetView.Toolbar.Visible = 'off';
            
            % No interactions
            app.SFTuningView.Interactions = [];
            app.receptiveFieldView.Interactions = [];
           
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Startup(app)
            app.autoScaleSurroundWeights = app.autoscalesurroundweightsCheckBox.Value;
            app.diffractionLimitedOptics = app.diffractionlimitedopticsCheckBox.Value;
            app.subjectRank = app.subjectPolans2015Spinner.Value;
            app.surroundGain = 1/app.centersurroundpeaksensitivitySlider.Value; 
            app.surroundRadius = app.surroundradiusdegsSlider.Value;
            app.surroundOffsetXDegs = app.xdegsSpinner.Value;
            app.surroundOffsetYDegs = app.ydegsSpinner.Value;
            app.initializeViews();
            app.loadSubjectData();
            app.computeWeights();
            app.visualizeWeights();
            app.computeSFTuning();
            app.plotSFTuning();
        end

        % Value changed function: subjectPolans2015Spinner
        function subjectIDChanged(app, event)
            app.subjectRank = app.subjectPolans2015Spinner.Value;
            app.loadSubjectData();
            app.computeWeights();
            app.visualizeWeights();
            app.computeSFTuning();
            app.plotSFTuning();
        end

        % Value changed function: 
        % centersurroundpeaksensitivitySlider
        function surroundGainChanged(app, event)
            app.surroundGain  = 1/app.centersurroundpeaksensitivitySlider.Value;
            app.computeWeights();
            app.visualizeWeights();
            app.computeSFTuning();
            app.plotSFTuning();
        end

        % Value changed function: surroundradiusdegsSlider
        function surroundRadiusChanged(app, event)
            app.surroundRadius = app.surroundradiusdegsSlider.Value;
            app.computeWeights();
            app.visualizeWeights();
            app.computeSFTuning();
            app.plotSFTuning();
        end

        % Value changed function: xdegsSpinner
        function xdegsSpinnerValueChanged(app, event)
            app.surroundOffsetXDegs = app.xdegsSpinner.Value;
            app.computeWeights();
            app.visualizeWeights();
            app.computeSFTuning();
            app.plotSFTuning();
        end

        % Value changed function: ydegsSpinner
        function ydegsSpinnerValueChanged(app, event)
            app.surroundOffsetYDegs = app.ydegsSpinner.Value;
            app.computeWeights();
            app.visualizeWeights();
            app.computeSFTuning();
            app.plotSFTuning();
        end

        % Value changed function: diffractionlimitedopticsCheckBox
        function diffractionlimitedopticsCheckBoxValueChanged(app, event)
            app.diffractionLimitedOptics = app.diffractionlimitedopticsCheckBox.Value;
            app.loadSubjectData();
            app.computeWeights();
            app.visualizeWeights();
            app.computeSFTuning();
            app.plotSFTuning();
        end

        % Value changed function: autoscalesurroundweightsCheckBox
        function autoscalesurroundweightsCheckBoxValueChanged(app, event)
            app.autoScaleSurroundWeights = app.autoscalesurroundweightsCheckBox.Value;
            app.visualizeWeights();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RGCspatialfrequencytuningsimulatorNPCottarisISETBioTeamUIFigure = uifigure('Visible', 'off');
            app.RGCspatialfrequencytuningsimulatorNPCottarisISETBioTeamUIFigure.Name = 'RGC spatial frequency tuning simulator  N.P. Cottaris, ISETBio Team';
            app.surroundradiusdegsSliderLabel = uilabel(app.RGCspatialfrequencytuningsimulatorNPCottarisISETBioTeamUIFigure);
            app.surroundradiusdegsSliderLabel.Text = 'surround radius (degs)';
            app.surroundradiusdegsSlider = uislider(app.RGCspatialfrequencytuningsimulatorNPCottarisISETBioTeamUIFigure);
            app.surroundradiusdegsSlider.Limits = [0 0.2];
            app.surroundradiusdegsSlider.MajorTicks = [0 0.02 0.04 0.06 0.08 0.1 0.12 0.14 0.16 0.18 0.2];
            app.surroundradiusdegsSlider.ValueChangedFcn = createCallbackFcn(app, @surroundRadiusChanged, true);
            app.surroundradiusdegsSlider.Value = 0.025;
            app.centersurroundpeaksensitivitySliderLabel = uilabel(app.RGCspatialfrequencytuningsimulatorNPCottarisISETBioTeamUIFigure);
            app.centersurroundpeaksensitivitySliderLabel.Text = 'center/surround peak sensitivity';
            app.centersurroundpeaksensitivitySlider = uislider(app.RGCspatialfrequencytuningsimulatorNPCottarisISETBioTeamUIFigure);
            app.centersurroundpeaksensitivitySlider.Limits = [5 400];
            app.centersurroundpeaksensitivitySlider.MajorTicks = [5 20 50 100 150 200 300 400];
            app.centersurroundpeaksensitivitySlider.MajorTickLabels = {'5', '20', '50', '100', '150', '200', '300', '400'};
            app.centersurroundpeaksensitivitySlider.ValueChangedFcn = createCallbackFcn(app, @surroundGainChanged, true);
            app.centersurroundpeaksensitivitySlider.Value = 50;
            app.subjectPolans2015SpinnerLabel = uilabel(app.RGCspatialfrequencytuningsimulatorNPCottarisISETBioTeamUIFigure);
            app.subjectPolans2015SpinnerLabel.Text = 'subject (Polans, 2015)';
            app.subjectPolans2015Spinner = uispinner(app.RGCspatialfrequencytuningsimulatorNPCottarisISETBioTeamUIFigure);
            app.subjectPolans2015Spinner.Limits = [1 10];
            app.subjectPolans2015Spinner.ValueChangedFcn = createCallbackFcn(app, @subjectIDChanged, true);
            app.subjectPolans2015Spinner.Value = 1;
            app.xdegsSpinnerLabel = uilabel(app.RGCspatialfrequencytuningsimulatorNPCottarisISETBioTeamUIFigure);
            app.xdegsSpinnerLabel.Text = 'x (degs)';
            app.xdegsSpinner = uispinner(app.RGCspatialfrequencytuningsimulatorNPCottarisISETBioTeamUIFigure);
            app.xdegsSpinner.Step = 0.002;
            app.xdegsSpinner.Limits = [-0.1 0.1];
            app.xdegsSpinner.ValueChangedFcn = createCallbackFcn(app, @xdegsSpinnerValueChanged, true);
            app.ydegsSpinnerLabel = uilabel(app.RGCspatialfrequencytuningsimulatorNPCottarisISETBioTeamUIFigure);
            app.ydegsSpinnerLabel.Text = 'y (degs)';
            app.ydegsSpinner = uispinner(app.RGCspatialfrequencytuningsimulatorNPCottarisISETBioTeamUIFigure);
            app.ydegsSpinner.Step = 0.002;
            app.ydegsSpinner.Limits = [-0.1 0.1];
            app.ydegsSpinner.ValueChangedFcn = createCallbackFcn(app, @ydegsSpinnerValueChanged, true);
            app.diffractionlimitedopticsCheckBox = uicheckbox(app.RGCspatialfrequencytuningsimulatorNPCottarisISETBioTeamUIFigure);
            app.diffractionlimitedopticsCheckBox.ValueChangedFcn = createCallbackFcn(app, @diffractionlimitedopticsCheckBoxValueChanged, true);
            app.diffractionlimitedopticsCheckBox.Text = 'diffraction limited optics';
            app.surroundoffsetLabel = uilabel(app.RGCspatialfrequencytuningsimulatorNPCottarisISETBioTeamUIFigure);
            app.surroundoffsetLabel.Text = {'surround'; 'offset'};
            app.integratedsensitivtysurroundcenterEditField = uieditfield(app.RGCspatialfrequencytuningsimulatorNPCottarisISETBioTeamUIFigure, 'numeric');
            app.integratedsensitivtysurroundcenterEditField.ValueDisplayFormat = '%2.2f';
            app.integratedsensitivtysurroundcenterEditField.Enable = 'off';
            app.integratedsensitivitysurroundcenterLabel = uilabel(app.RGCspatialfrequencytuningsimulatorNPCottarisISETBioTeamUIFigure);
            app.integratedsensitivitysurroundcenterLabel.Text = {'integrated sensitivity'; '(surround/center)'};
            app.autoscalesurroundweightsCheckBox = uicheckbox(app.RGCspatialfrequencytuningsimulatorNPCottarisISETBioTeamUIFigure);
            app.autoscalesurroundweightsCheckBox.ValueChangedFcn = createCallbackFcn(app, @autoscalesurroundweightsCheckBoxValueChanged, true);
            app.autoscalesurroundweightsCheckBox.Text = 'auto-scale surround weights';
            app.receptiveFieldView = uiaxes(app.RGCspatialfrequencytuningsimulatorNPCottarisISETBioTeamUIFigure);
            xlabel(app.receptiveFieldView, 'X')
            ylabel(app.receptiveFieldView, 'Y')
            zlabel(app.receptiveFieldView, 'Z')
            app.SFTuningView = uiaxes(app.RGCspatialfrequencytuningsimulatorNPCottarisISETBioTeamUIFigure);
            xlabel(app.SFTuningView, 'spatial frequency (c/deg)')
            ylabel(app.SFTuningView, 'response')
            zlabel(app.SFTuningView, 'Z')
            app.SFTuningView.XLim = [0.4 100];
            app.SFTuningView.XTick = [0.5 1 2 4 6 10 20 40 60 100];
            app.SFTuningView.XScale = 'log';
            app.SFTuningView.XTickLabel = {'0.5'; '1'; '2'; '4'; '6'; '10'; '20'; '40'; '60'; '100'};
            app.SFTuningView.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.psfInsetView = uiaxes(app.RGCspatialfrequencytuningsimulatorNPCottarisISETBioTeamUIFigure);
            app.psfInsetView.XTick = [];
            app.psfInsetView.YTick = [];
            % Show the figure after all components are created
            app.RGCspatialfrequencytuningsimulatorNPCottarisISETBioTeamUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RGCSFtunningSimulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RGCspatialfrequencytuningsimulatorNPCottarisISETBioTeamUIFigure)

            % Execute the startup function
            runStartupFcn(app, @Startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RGCspatialfrequencytuningsimulatorNPCottarisISETBioTeamUIFigure)
        end
    end
end